classdef HB11TEA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InputsTab                       matlab.ui.container.Tab
        InputGrid                       matlab.ui.container.GridLayout
        InputNavigationBar              matlab.ui.container.TabGroup
        InputLaserTab                   matlab.ui.container.Tab
        InputLaserGrid                  matlab.ui.container.GridLayout
        InputLaserPanel                 matlab.ui.container.Panel
        InputLaserSubGrid               matlab.ui.container.GridLayout
        LaserEfficiencyDecimalFormEditField_2  matlab.ui.control.EditField
        LaserEfficiencyDecimalFormEditField_2Label  matlab.ui.control.Label
        RepetitionRateHzEditField_2     matlab.ui.control.EditField
        RepetitionRateHzEditField_2Label  matlab.ui.control.Label
        TotalLaserEnergyPerPulseMJEditField_2  matlab.ui.control.EditField
        TotalLaserEnergyPerPulseMJEditField_2Label  matlab.ui.control.Label
        InputReactorTab                 matlab.ui.container.Tab
        InputReactorGrid                matlab.ui.container.GridLayout
        ReactorPanel                    matlab.ui.container.Panel
        InputReactorSubGrid             matlab.ui.container.GridLayout
        DutyCycleDecimalFormEditField_2  matlab.ui.control.EditField
        DutyCycleDecimalFormEditField_2Label  matlab.ui.control.Label
        IncreasedGainbyXFactorRatioEditField_2  matlab.ui.control.EditField
        IncreasedGainbyXFactorRatioEditField_2Label  matlab.ui.control.Label
        TargetGainRatioEditField_2      matlab.ui.control.EditField
        TargetGainRatioEditField_2Label  matlab.ui.control.Label
        InputPowerTab                   matlab.ui.container.Tab
        InputPowerGrid                  matlab.ui.container.GridLayout
        InputPowerPanel                 matlab.ui.container.Panel
        InputPowerSubGrid               matlab.ui.container.GridLayout
        ElectricityRateMWhEditField_2   matlab.ui.control.EditField
        ElectricityRateMWhEditField_2Label  matlab.ui.control.Label
        ElectricityGeneratorEfficiencyDecimalFormEditField_2  matlab.ui.control.EditField
        ElectricityGeneratorEfficiencyDecimalFormEditField_2Label  matlab.ui.control.Label
        InputFuelManTab                 matlab.ui.container.Tab
        InputFuelManGrid                matlab.ui.container.GridLayout
        InputFuelManPanel               matlab.ui.container.Panel
        InputFuelManSubGrid             matlab.ui.container.GridLayout
        CapacitiveCoilandHydrogenConeBooleanEditField_2  matlab.ui.control.EditField
        CapacitiveCoilandHydrogenConeBooleanEditField_2Label  matlab.ui.control.Label
        TotalCostofXFactorEditField_2   matlab.ui.control.EditField
        TotalCostofXFactorEditField_2Label  matlab.ui.control.Label
        XFactorBooleanEditField_2       matlab.ui.control.EditField
        XFactorBooleanEditField_2Label  matlab.ui.control.Label
        FuelStoreTab                    matlab.ui.container.Tab
        InputFuelStoreGrid              matlab.ui.container.GridLayout
        InputFuelStorePanel             matlab.ui.container.Panel
        InputFuelStoreSubGrid           matlab.ui.container.GridLayout
        InputMaintenanceTab             matlab.ui.container.Tab
        InputMaintenanceGrid            matlab.ui.container.GridLayout
        InputMaintenancePanel           matlab.ui.container.Panel
        InputMaintenanceSubGrid         matlab.ui.container.GridLayout
        NumberofYearsinServiceYearsEditField_2  matlab.ui.control.EditField
        NumberofYearsinServiceYearsEditField_2Label  matlab.ui.control.Label
        InputConstructionTab            matlab.ui.container.Tab
        InputConstructionGrid           matlab.ui.container.GridLayout
        InputConstructionPanel          matlab.ui.container.Panel
        InputConstructionSubGrid        matlab.ui.container.GridLayout
        InputCoolingTab                 matlab.ui.container.Tab
        InputCoolingGrid                matlab.ui.container.GridLayout
        InputCoolingPanel               matlab.ui.container.Panel
        InputCoolingSubGrid             matlab.ui.container.GridLayout
        InputPersonnelTab               matlab.ui.container.Tab
        InputPersonnelGrid              matlab.ui.container.GridLayout
        InputPersonnelPanel             matlab.ui.container.Panel
        InputPersonnelSubGrid           matlab.ui.container.GridLayout
        NumberofStaffStaffEditField_2   matlab.ui.control.EditField
        NumberofStaffStaffEditField_2Label  matlab.ui.control.Label
        InputVacuumTab                  matlab.ui.container.Tab
        InputVacuumGrid                 matlab.ui.container.GridLayout
        InputVacuumPanel                matlab.ui.container.Panel
        InputPanelSubGrid               matlab.ui.container.GridLayout
        MassFlowRateLsEditField_2       matlab.ui.control.EditField
        MassFlowRateLsEditField_2Label  matlab.ui.control.Label
        InputActions                    matlab.ui.container.GridLayout
        RunModelButton                  matlab.ui.control.Button
        OutputsTab                      matlab.ui.container.Tab
        OutputGrid                      matlab.ui.container.GridLayout
        OutputNavigationBar             matlab.ui.container.TabGroup
        OutputLaserTab                  matlab.ui.container.Tab
        OutputLaserGrid                 matlab.ui.container.GridLayout
        OutputLaserPanel                matlab.ui.container.Panel
        OutputLaserSubPanel             matlab.ui.container.GridLayout
        OutputReactorTab                matlab.ui.container.Tab
        OutputReactorGrid               matlab.ui.container.GridLayout
        OutputReactorPanel              matlab.ui.container.Panel
        OutputReactorSubGrid            matlab.ui.container.GridLayout
        ThermalReactionEnergyOutputMJEditField  matlab.ui.control.EditField
        ThermalReactionEnergyOutputMJEditFieldLabel  matlab.ui.control.Label
        OutputPowerTab                  matlab.ui.container.Tab
        OutputPowerGrid                 matlab.ui.container.GridLayout
        OutputPowerPanel                matlab.ui.container.Panel
        OutputReactorSubPanel           matlab.ui.container.GridLayout
        YearlyNetPowerOutputMWhEditField_2  matlab.ui.control.EditField
        YearlyNetPowerOutputMWhEditField_2Label  matlab.ui.control.Label
        HoursInOperationPerYearHrsEditField_2  matlab.ui.control.EditField
        HoursInOperationPerYearHrsEditField_2Label  matlab.ui.control.Label
        NetPowerOutputMWhEditField      matlab.ui.control.EditField
        NetPowerOutputMWhEditFieldLabel  matlab.ui.control.Label
        TotalReactorEnergyConsumptionMWhEditField_4  matlab.ui.control.EditField
        TotalReactorEnergyConsumptionMWhEditField_4Label  matlab.ui.control.Label
        GrossPowerOutputMWhEditField    matlab.ui.control.EditField
        GrossPowerOutputMWhEditFieldLabel  matlab.ui.control.Label
        RealisedReactorGainRatioEditField_2  matlab.ui.control.EditField
        RealisedReactorGainRatioEditField_2Label  matlab.ui.control.Label
        OutputFuelManTab                matlab.ui.container.Tab
        OutputFuelManGrid               matlab.ui.container.GridLayout
        OutputFuelManPanel              matlab.ui.container.Panel
        OutputFuelManSubGrid            matlab.ui.container.GridLayout
        TotalFuelManufacturingCostsOngoingYearEditField  matlab.ui.control.EditField
        TotalFuelManufacturingCostsOngoingYearEditFieldLabel  matlab.ui.control.Label
        TotalFuelManufacturingCostsUpfrontEditField_2  matlab.ui.control.EditField
        TotalFuelManufacturingCostsUpfrontEditField_2Label  matlab.ui.control.Label
        TotalCostPerTargetEditField_2   matlab.ui.control.EditField
        TotalCostPerTargetEditField_2Label  matlab.ui.control.Label
        OutputFuelStoreTab              matlab.ui.container.Tab
        OutputFuelStoreGrid             matlab.ui.container.GridLayout
        OutputFuelStorePanel            matlab.ui.container.Panel
        OutputFuelStoreSubGrid          matlab.ui.container.GridLayout
        TotalCostofFuelDeliveryandStorageEditField_2  matlab.ui.control.EditField
        TotalCostofFuelDeliveryandStorageEditField_2Label  matlab.ui.control.Label
        OutputMaintenanceTab            matlab.ui.container.Tab
        OutputMaintenanceGrid           matlab.ui.container.GridLayout
        OutputMaintenancePanel          matlab.ui.container.Panel
        OutputMaintenanceSubGrid        matlab.ui.container.GridLayout
        LifetimeMaintenanceCostEditField_2  matlab.ui.control.EditField
        LifetimeMaintenanceCostEditField_2Label  matlab.ui.control.Label
        OutputConstructionTab           matlab.ui.container.Tab
        OutputConstructionGrid          matlab.ui.container.GridLayout
        OutputConstructionPanel         matlab.ui.container.Panel
        OutputConstructionSubGrid       matlab.ui.container.GridLayout
        TotalConstructionCostEditField_2  matlab.ui.control.EditField
        TotalConstructionCostEditField_2Label  matlab.ui.control.Label
        OutputCoolingTab                matlab.ui.container.Tab
        OutputCoolingGrid               matlab.ui.container.GridLayout
        OutputCoolingPanel              matlab.ui.container.Panel
        OutputCoolingSubGrid            matlab.ui.container.GridLayout
        OutputPersonnelTab              matlab.ui.container.Tab
        OutputPersonnelGrid             matlab.ui.container.GridLayout
        OutputPersonnelPanel            matlab.ui.container.Panel
        OutputPersonnelSubPanel         matlab.ui.container.GridLayout
        YearlyPersonnelCostEditField_2  matlab.ui.control.EditField
        YearlyPersonnelCostEditField_2Label  matlab.ui.control.Label
        OutputVacuumTab                 matlab.ui.container.Tab
        OutputVacuumGrid                matlab.ui.container.GridLayout
        OutputVacuumPanel               matlab.ui.container.Panel
        OutputVacuumSubGrid             matlab.ui.container.GridLayout
        TotalCostofVacuumSystemEditField  matlab.ui.control.EditField
        TotalCostofVacuumSystemEditFieldLabel  matlab.ui.control.Label
        OutputOverallTab                matlab.ui.container.Tab
        OutputOverallGrid               matlab.ui.container.GridLayout
        OutputOverallPanel              matlab.ui.container.Panel
        OutputOverallSubGrid            matlab.ui.container.GridLayout
        LifetimeProfitWithoutInflationEditField_2  matlab.ui.control.EditField
        LifetimeProfitWithoutInflationEditField_2Label  matlab.ui.control.Label
        LifetimeCostWithoutInflationEditField_2  matlab.ui.control.EditField
        LifetimeCostWithoutInflationEditField_2Label  matlab.ui.control.Label
        YearlyProfitEditField_2         matlab.ui.control.EditField
        YearlyProfitEditField_2Label    matlab.ui.control.Label
        YearlyGrossRevenueEditField_2   matlab.ui.control.EditField
        YearlyGrossRevenueEditField_2Label  matlab.ui.control.Label
        YearlyCostEditField_2           matlab.ui.control.EditField
        YearlyCostEditField_2Label      matlab.ui.control.Label
        CapitalCostEditField_2          matlab.ui.control.EditField
        CapitalCostEditField_2Label     matlab.ui.control.Label
        OutputLCOETab                   matlab.ui.container.Tab
        OutputLCOEGrid                  matlab.ui.container.GridLayout
        OutputLCOEPanel                 matlab.ui.container.Panel
        OutputLCOESubGrid               matlab.ui.container.GridLayout
        LCOEMWhEditField_2              matlab.ui.control.EditField
        LCOEMWhEditField_2Label         matlab.ui.control.Label
        CostofDecomissioningMWhEditField_2  matlab.ui.control.EditField
        CostofDecomissioningMWhEditField_2Label  matlab.ui.control.Label
        AnnualCostofFuelEditField_2     matlab.ui.control.EditField
        AnnualCostofFuelEditField_2Label  matlab.ui.control.Label
        AnnualCostofScheduledPartReplacementsEditField_2  matlab.ui.control.EditField
        AnnualCostofScheduledPartReplacementsEditField_2Label  matlab.ui.control.Label
        AnnualCostofOperationsandMaintenanceEditField_2  matlab.ui.control.EditField
        AnnualCostofOperationsandMaintenanceEditField_2Label  matlab.ui.control.Label
        AnnualCostofUpfrontCapitalEditField_2  matlab.ui.control.EditField
        AnnualCostofUpfrontCapitalEditField_2Label  matlab.ui.control.Label
        OutputProjectionsTab            matlab.ui.container.Tab
        OutputProjectionsGrid           matlab.ui.container.Panel
        OutputProjectionsSubGrid        matlab.ui.container.GridLayout
        OutputProjectionsSubSubGrid     matlab.ui.container.GridLayout
        ProjectedLifetimeNetRevenueEditField  matlab.ui.control.EditField
        ProjectedLifetimeNetRevenueEditFieldLabel  matlab.ui.control.Label
        ProjectedLifetimeGrossRevenueEditField  matlab.ui.control.EditField
        ProjectedLifetimeGrossRevenueEditFieldLabel  matlab.ui.control.Label
        ProjectedLifetimeCostEditField  matlab.ui.control.EditField
        ProjectedLifetimeCostEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        OptionsTab                      matlab.ui.container.Tab
        OptionsLayout                   matlab.ui.container.GridLayout
        SaveButton                      matlab.ui.control.Button
        SavethissessionLabel            matlab.ui.control.Label
        OpenButton                      matlab.ui.control.Button
        OpenaprevioussessionLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        AppLayer = ApplicationModule() % Instance of the Application Layer
    end
    
    methods (Access = private)
        
        % p_MapApplicationLayerToInputs : Sets inputs to data in store
        function p_MapApplicationLayerToInputs(app)

            % --- Extract Input Values from Application Layer ---
            inputs = app.AppLayer.PostInputs();

            % --- Load Into Input Fields ---
            children = app.InputPanelLayout.Children;

            % Check that number of children is even
            num_children = length(children);

            if mod(num_children, 2) ~= 0
                error("Failed to parse inputs.");
            end
            
            % Note : This loop depends on assumptions 1 and 2 detailed above
            for i = 1:2:(num_children - 1)
                
                % Process Key (Remove Spaces, Set Lowercase append input specifier)
                key = children(i).Text;
                key = strrep(key, " ", "");
                key = lower(key);
                key = strcat("in_", key);
                
                % Set Value
                children(i+1).Value = inputs.(key);

            end
            
        end
       
        
        function p_MapApplicationLayerToOutputs(app)
            
            % --- Extract Outputs from Application Layer ---
            outputs = app.AppLayer.PostOutputs();

            % --- Load Into UI Outputs ---
            app.p_PlotElectricityGenerated(outputs);

        end

        % MatchLabelToOutput : Gets correct value (str) for a label in outputs
        function value = MatchLabelToOutput(app, label, output)
            
            key = app.ParseUIInputLabel(label, 'OUT_');
            
            if isfield(output, key)
                value = num2str(output.(key));
            else
                value = "NONE";
            end


        end

        % PlotCostData : Plots cost data
        function PlotCostData(app, data)
            disp(class(data));
            % Assuming time starts from 0 and increments by 1 year for each data point
            time = 0:numel(data)-1;
        
            % Plot the data on the axes
            plot(app.UIAxes, time, data, 'LineWidth', 2);
            % 
            % % Set labels for the axes
            % xlabel(app.UIAxes, 'Time (Years)');
            % ylabel(app.UIAxes, 'Dollar Value');
            % 
            % % Optionally, you can set the title for the plot
            % title(app.UIAxes, 'Dollar Value vs Time');
        
            % % Optionally, you can adjust the axis limits for a better view
            % xlim(app.UIAxes, [0, numel(data)-1]);
            % 
            % % Automatically set x-axis tick positions to match the time values
            % app.UIAxes.XTick = time;
            % 
            % % Manually set y-axis tick positions to show only some data points
            % numTicks = 5;
            % idx = round(linspace(1, numel(data), numTicks));
            % app.UIAxes.YTick = data(idx);
            % 
            % % Set the y-axis limits to match the minimum and maximum values of the data
            % ylim(app.UIAxes, [min(data), max(data)]);

        end

        % PopulateUIOutputBoxes : Populates the output boxes
        function PopulateUIOutputBoxes(app, outputs)

            parent = app.OutputNavigationBar;

            % Loop through tabs
            for i = 1:numel(parent.Children)
                
                child = parent.Children(i);
                
                % Drill to bottom of Component tree
                while isprop(child, 'Children')
                   

                    child_count = numel(child.Children);
                    pref_child = false;

                    if child_count > 1
                        
                        for k = 1:child_count
                            
                            if isa(child.Children(k), 'matlab.ui.container.GridLayout')
                                pref_child = child.Children(k);
                                break;
                            end

                        end

                    end

                    if pref_child ~= false
                        child = pref_child;
                    else
                        child = child.Children;
                    end

                end

                % Extract text boxes
                if isa(child, 'matlab.ui.control.internal.model.ComponentModel')

                    % Assumption : Graphics array is [lbl, val, lbl, val]

                    for j = 1:2:numel(child)-1
                        
                        label = child(j).Text;
                        value = app.MatchLabelToOutput(label, outputs);
                  
                        child(j + 1).Value = value;

                    end
                end

            end

        end

        
        % IngestUIInputs : Pulls user inputs from UI into application layer
        function IngestUIInputs(app)
            
            parent_container = app.InputNavigationBar;
            boxes = app.ExtractUIBoxesFromTree(parent_container);

            app_inputs = app.PackageUIInputBoxes(boxes);
            app.AppLayer.LoadInputs(app_inputs);
           
        end
        
        
        function boxes = ExtractUIBoxesFromTree(app, parent_container)

           boxes = [];
            
           % Loop through tabs
           for i = 1:numel(parent_container.Children)

                child = parent_container.Children(i);
                
                % Drill to bottom of Component tree
                while isprop(child, 'Children')
                   

                    child_count = numel(child.Children);
                    pref_child = false;

                    if child_count > 1
                        
                        for k = 1:child_count
                            
                            if isa(child.Children(k), 'matlab.ui.container.GridLayout')
                                pref_child = child.Children(k);
                                break;
                            end

                        end

                    end

                    if pref_child ~= false
                        child = pref_child;
                    else
                        child = child.Children;
                    end


                end
                
                % Extract text boxes
                if isa(child, 'matlab.ui.control.internal.model.ComponentModel')

                    % Assumption : Graphics array is [lbl, val, lbl, val]

                    for j = 1:2:numel(child)-1
                        
                        label = child(j).Text;
                        value = child(j + 1).Value;

                        box = struct("label", label, "value", value);
                        boxes = [boxes, box];

                    end
                end
           end
        end

        % ParseUIInputLabel : Parses ui input label for app layer
        function parsed = ParseUIInputLabel(app, label, prefix)
            % Replace spaces with "_"
            parsed = regexprep(label, ' ', '_');
            
            % Convert to lowercase
            parsed = lower(parsed);
            
            % Ignore values between "(" and ")"
            parsed = regexprep(parsed, '\([^)]*\)', '');
            
            % Add the "in_" prefix
            parsed = [prefix, parsed];
        end


        % PackageUIInputBoxes : Packages Inputs for Application Layer
        % Returns a struct
        function packaged_boxes = PackageUIInputBoxes(app, boxes)
             
            packaged_boxes = struct();

            for i = 1:numel(boxes)
                packaged_label = app.ParseUIInputLabel(boxes(i).label, 'IN_');
                packaged_value = str2double(boxes(i).value);
                packaged_boxes.(packaged_label) = packaged_value;
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function OutputNavTreeSelectionChanged(app, event)
            
            % Array of selected TreeNodes
            selectedNodes = app.OutputNavTree.SelectedNodes;

            % --- Check if the node is a parent (Has Children) ---
            if ~isempty(selectedNodes.Children)
                return;
            end
            
            % --- Change the Title ---
            selectedOutputName= selectedNodes.Text;
            app.OutputPanel.Title = selectedOutputName;

            % --- Change the Rendered Content Panel ---

            % Clear all selected panels
            set(app.OutputPanel.Children, 'Visible', 'off');
            
            % Conditionally show a child panel
            switch selectedOutputName
           
            end


        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            % Get File Path
            [filename, path] = uigetfile('*.xml');
            
            % Handle the user cancelling
            if filename == 0
                return;
            end
            
            % Load File into Memory
            app.AppLayer.OpenState(strcat(path, filename));
            app.p_MapApplicationLayerToInputs();
            app.p_MapApplicationLayerToOutputs();

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            % Select Output Destination
            [filename, path] = uiputfile('*.xml');

            % Handle User Cancel
            if filename == 0
                return;
            end

            % Save to specified file
            app.AppLayer.SaveState(strcat(path, filename));

        end

        % Button pushed function: RunModelButton
        function RunModelButtonPushed(app, event)
            

            % --- Load Inputs into the application layer ---
            IngestUIInputs(app);

            % --- Run the Model ---
            app.AppLayer.RunModel();
            
            % --- Load Outputs into the UI layer ---
            outputs = app.AppLayer.PostOutputs();
            app.PopulateUIOutputBoxes(outputs);
            
            if isfield(outputs, 'OUT_total_project_cost_ts_')
                app.PlotCostData(outputs.OUT_total_project_cost_ts_);
            end
         
            % --- Update Outputs in UI ---


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.InputGrid = uigridlayout(app.InputsTab);
            app.InputActions = uigridlayout(app.InputGrid);
            app.RunModelButton = uibutton(app.InputActions, 'push');
            app.RunModelButton.ButtonPushedFcn = createCallbackFcn(app, @RunModelButtonPushed, true);
            app.RunModelButton.Text = 'Run Model';
            app.InputNavigationBar = uitabgroup(app.InputGrid);
            app.InputLaserTab = uitab(app.InputNavigationBar);
            app.InputLaserTab.Title = 'Laser';
            app.InputLaserGrid = uigridlayout(app.InputLaserTab);
            app.InputLaserPanel = uipanel(app.InputLaserGrid);
            app.InputLaserPanel.Title = 'Primary Laser';
            app.InputLaserSubGrid = uigridlayout(app.InputLaserPanel);
            app.TotalLaserEnergyPerPulseMJEditField_2Label = uilabel(app.InputLaserSubGrid);
            app.TotalLaserEnergyPerPulseMJEditField_2Label.Text = 'Total Laser Energy Per Pulse (MJ)';
            app.TotalLaserEnergyPerPulseMJEditField_2 = uieditfield(app.InputLaserSubGrid, 'text');
            app.TotalLaserEnergyPerPulseMJEditField_2.Value = '20';
            app.RepetitionRateHzEditField_2Label = uilabel(app.InputLaserSubGrid);
            app.RepetitionRateHzEditField_2Label.Text = 'Repetition Rate (Hz)';
            app.RepetitionRateHzEditField_2 = uieditfield(app.InputLaserSubGrid, 'text');
            app.RepetitionRateHzEditField_2.Value = '1';
            app.LaserEfficiencyDecimalFormEditField_2Label = uilabel(app.InputLaserSubGrid);
            app.LaserEfficiencyDecimalFormEditField_2Label.Text = 'Laser Efficiency (% Decimal Form)';
            app.LaserEfficiencyDecimalFormEditField_2 = uieditfield(app.InputLaserSubGrid, 'text');
            app.LaserEfficiencyDecimalFormEditField_2.Value = '0.036';
            app.InputReactorTab = uitab(app.InputNavigationBar);
            app.InputReactorTab.Title = 'Reactor';
            app.InputReactorGrid = uigridlayout(app.InputReactorTab);
            app.ReactorPanel = uipanel(app.InputReactorGrid);
            app.ReactorPanel.Title = 'Reactor';
            app.InputReactorSubGrid = uigridlayout(app.ReactorPanel);
            app.TargetGainRatioEditField_2Label = uilabel(app.InputReactorSubGrid);
            app.TargetGainRatioEditField_2Label.Text = 'Target Gain (Ratio)';
            app.TargetGainRatioEditField_2 = uieditfield(app.InputReactorSubGrid, 'text');
            app.TargetGainRatioEditField_2.Value = '150';
            app.IncreasedGainbyXFactorRatioEditField_2Label = uilabel(app.InputReactorSubGrid);
            app.IncreasedGainbyXFactorRatioEditField_2Label.Text = 'Increased Gain by X Factor (Ratio)';
            app.IncreasedGainbyXFactorRatioEditField_2 = uieditfield(app.InputReactorSubGrid, 'text');
            app.IncreasedGainbyXFactorRatioEditField_2.Value = '2';
            app.DutyCycleDecimalFormEditField_2Label = uilabel(app.InputReactorSubGrid);
            app.DutyCycleDecimalFormEditField_2Label.Text = 'Duty Cycle (% Decimal Form)';
            app.DutyCycleDecimalFormEditField_2 = uieditfield(app.InputReactorSubGrid, 'text');
            app.DutyCycleDecimalFormEditField_2.Value = '0.66';
            app.InputPowerTab = uitab(app.InputNavigationBar);
            app.InputPowerTab.Title = 'Power';
            app.InputPowerGrid = uigridlayout(app.InputPowerTab);
            app.InputPowerPanel = uipanel(app.InputPowerGrid);
            app.InputPowerPanel.Title = 'Power Generation';
            app.InputPowerSubGrid = uigridlayout(app.InputPowerPanel);
            app.ElectricityGeneratorEfficiencyDecimalFormEditField_2Label = uilabel(app.InputPowerSubGrid);
            app.ElectricityGeneratorEfficiencyDecimalFormEditField_2Label.Text = 'Electricity Generator Efficiency (% Decimal Form)';
            app.ElectricityGeneratorEfficiencyDecimalFormEditField_2 = uieditfield(app.InputPowerSubGrid, 'text');
            app.ElectricityGeneratorEfficiencyDecimalFormEditField_2.Value = '0.4';
            app.ElectricityRateMWhEditField_2Label = uilabel(app.InputPowerSubGrid);
            app.ElectricityRateMWhEditField_2Label.Text = 'Electricity Rate ($/MWh)';
            app.ElectricityRateMWhEditField_2 = uieditfield(app.InputPowerSubGrid, 'text');
            app.ElectricityRateMWhEditField_2.Value = '72';
            app.InputFuelManTab = uitab(app.InputNavigationBar);
            app.InputFuelManTab.Title = 'Fuel Man';
            app.InputFuelManGrid = uigridlayout(app.InputFuelManTab);
            app.InputFuelManPanel = uipanel(app.InputFuelManGrid);
            app.InputFuelManPanel.Title = 'Fuel Manufacturing';
            app.InputFuelManSubGrid = uigridlayout(app.InputFuelManPanel);
            app.XFactorBooleanEditField_2Label = uilabel(app.InputFuelManSubGrid);
            app.XFactorBooleanEditField_2Label.Text = 'X Factor (Boolean)';
            app.XFactorBooleanEditField_2 = uieditfield(app.InputFuelManSubGrid, 'text');
            app.XFactorBooleanEditField_2.Value = '0';
            app.TotalCostofXFactorEditField_2Label = uilabel(app.InputFuelManSubGrid);
            app.TotalCostofXFactorEditField_2Label.Text = 'Total Cost of X Factor ($)';
            app.TotalCostofXFactorEditField_2 = uieditfield(app.InputFuelManSubGrid, 'text');
            app.TotalCostofXFactorEditField_2.Value = '100';
            app.CapacitiveCoilandHydrogenConeBooleanEditField_2Label = uilabel(app.InputFuelManSubGrid);
            app.CapacitiveCoilandHydrogenConeBooleanEditField_2Label.Text = 'Capacitive Coil and Hydrogen Cone (Boolean)';
            app.CapacitiveCoilandHydrogenConeBooleanEditField_2 = uieditfield(app.InputFuelManSubGrid, 'text');
            app.CapacitiveCoilandHydrogenConeBooleanEditField_2.Value = '1';
            app.FuelStoreTab = uitab(app.InputNavigationBar);
            app.FuelStoreTab.Title = 'Fuel Store';
            app.InputFuelStoreGrid = uigridlayout(app.FuelStoreTab);
            app.InputFuelStorePanel = uipanel(app.InputFuelStoreGrid);
            app.InputFuelStorePanel.Title = 'Fuel Storage / Delivery';
            app.InputFuelStoreSubGrid = uigridlayout(app.InputFuelStorePanel);
            app.InputMaintenanceTab = uitab(app.InputNavigationBar);
            app.InputMaintenanceTab.Title = 'Maintenance';
            app.InputMaintenanceGrid = uigridlayout(app.InputMaintenanceTab);
            app.InputMaintenancePanel = uipanel(app.InputMaintenanceGrid);
            app.InputMaintenancePanel.Title = 'Maintenance';
            app.InputMaintenanceSubGrid = uigridlayout(app.InputMaintenancePanel);
            app.NumberofYearsinServiceYearsEditField_2Label = uilabel(app.InputMaintenanceSubGrid);
            app.NumberofYearsinServiceYearsEditField_2Label.Text = 'Number of Years in Service (Years)';
            app.NumberofYearsinServiceYearsEditField_2 = uieditfield(app.InputMaintenanceSubGrid, 'text');
            app.NumberofYearsinServiceYearsEditField_2.Value = '25';
            app.InputConstructionTab = uitab(app.InputNavigationBar);
            app.InputConstructionTab.Title = 'Construction';
            app.InputConstructionGrid = uigridlayout(app.InputConstructionTab);
            app.InputConstructionPanel = uipanel(app.InputConstructionGrid);
            app.InputConstructionPanel.Title = 'Construction';
            app.InputConstructionSubGrid = uigridlayout(app.InputConstructionPanel);
            app.InputCoolingTab = uitab(app.InputNavigationBar);
            app.InputCoolingTab.Title = 'Cooling';
            app.InputCoolingGrid = uigridlayout(app.InputCoolingTab);
            app.InputCoolingPanel = uipanel(app.InputCoolingGrid);
            app.InputCoolingPanel.Title = 'Cooling System';
            app.InputCoolingSubGrid = uigridlayout(app.InputCoolingPanel);
            app.InputPersonnelTab = uitab(app.InputNavigationBar);
            app.InputPersonnelTab.Title = 'Personnel';
            app.InputPersonnelGrid = uigridlayout(app.InputPersonnelTab);
            app.InputPersonnelPanel = uipanel(app.InputPersonnelGrid);
            app.InputPersonnelPanel.Title = 'Personnel';
            app.InputPersonnelSubGrid = uigridlayout(app.InputPersonnelPanel);
            app.NumberofStaffStaffEditField_2Label = uilabel(app.InputPersonnelSubGrid);
            app.NumberofStaffStaffEditField_2Label.Text = 'Number of Staff (Staff)';
            app.NumberofStaffStaffEditField_2 = uieditfield(app.InputPersonnelSubGrid, 'text');
            app.NumberofStaffStaffEditField_2.Value = '300';
            app.InputVacuumTab = uitab(app.InputNavigationBar);
            app.InputVacuumTab.Title = 'Vacuum';
            app.InputVacuumGrid = uigridlayout(app.InputVacuumTab);
            app.InputVacuumPanel = uipanel(app.InputVacuumGrid);
            app.InputVacuumPanel.Title = 'Vacuum System';
            app.InputPanelSubGrid = uigridlayout(app.InputVacuumPanel);
            app.MassFlowRateLsEditField_2Label = uilabel(app.InputPanelSubGrid);
            app.MassFlowRateLsEditField_2Label.Text = 'Mass Flow Rate (L/s)';
            app.MassFlowRateLsEditField_2 = uieditfield(app.InputPanelSubGrid, 'text');
            app.MassFlowRateLsEditField_2.Value = '32000';
            app.OutputsTab = uitab(app.TabGroup);
            app.OutputsTab.Title = 'Outputs';
            app.OutputGrid = uigridlayout(app.OutputsTab);
            app.OutputNavigationBar = uitabgroup(app.OutputGrid);
            app.OutputLaserTab = uitab(app.OutputNavigationBar);
            app.OutputLaserTab.Title = 'Laser';
            app.OutputLaserGrid = uigridlayout(app.OutputLaserTab);
            app.OutputLaserPanel = uipanel(app.OutputLaserGrid);
            app.OutputLaserPanel.Title = 'Primary Laser';
            app.OutputLaserSubPanel = uigridlayout(app.OutputLaserPanel);
            app.OutputReactorTab = uitab(app.OutputNavigationBar);
            app.OutputReactorTab.Title = 'Reactor';
            app.OutputReactorGrid = uigridlayout(app.OutputReactorTab);
            app.OutputReactorPanel = uipanel(app.OutputReactorGrid);
            app.OutputReactorPanel.Title = 'Reactor';
            app.OutputReactorSubGrid = uigridlayout(app.OutputReactorPanel);
            app.ThermalReactionEnergyOutputMJEditFieldLabel = uilabel(app.OutputReactorSubGrid);
            app.ThermalReactionEnergyOutputMJEditFieldLabel.Text = 'Thermal Reaction Energy Output (MJ)';
            app.ThermalReactionEnergyOutputMJEditField = uieditfield(app.OutputReactorSubGrid, 'text');
            app.ThermalReactionEnergyOutputMJEditField.Editable = 'off';
            app.ThermalReactionEnergyOutputMJEditField.Value = '0';
            app.OutputPowerTab = uitab(app.OutputNavigationBar);
            app.OutputPowerTab.Title = 'Power';
            app.OutputPowerGrid = uigridlayout(app.OutputPowerTab);
            app.OutputPowerPanel = uipanel(app.OutputPowerGrid);
            app.OutputPowerPanel.Title = 'Power Generation';
            app.OutputReactorSubPanel = uigridlayout(app.OutputPowerPanel);
            app.RealisedReactorGainRatioEditField_2Label = uilabel(app.OutputReactorSubPanel);
            app.RealisedReactorGainRatioEditField_2Label.Text = 'Realised Reactor Gain (Ratio)';
            app.RealisedReactorGainRatioEditField_2 = uieditfield(app.OutputReactorSubPanel, 'text');
            app.RealisedReactorGainRatioEditField_2.Editable = 'off';
            app.RealisedReactorGainRatioEditField_2.Value = '0';
            app.GrossPowerOutputMWhEditFieldLabel = uilabel(app.OutputReactorSubPanel);
            app.GrossPowerOutputMWhEditFieldLabel.Text = 'Gross Power Output (MWh)';
            app.GrossPowerOutputMWhEditField = uieditfield(app.OutputReactorSubPanel, 'text');
            app.GrossPowerOutputMWhEditField.Editable = 'off';
            app.GrossPowerOutputMWhEditField.Value = '0';
            app.TotalReactorEnergyConsumptionMWhEditField_4Label = uilabel(app.OutputReactorSubPanel);
            app.TotalReactorEnergyConsumptionMWhEditField_4Label.Text = 'Total Reactor Energy Consumption (MWh)';
            app.TotalReactorEnergyConsumptionMWhEditField_4 = uieditfield(app.OutputReactorSubPanel, 'text');
            app.TotalReactorEnergyConsumptionMWhEditField_4.Editable = 'off';
            app.TotalReactorEnergyConsumptionMWhEditField_4.Value = '0';
            app.NetPowerOutputMWhEditFieldLabel = uilabel(app.OutputReactorSubPanel);
            app.NetPowerOutputMWhEditFieldLabel.Text = 'Net Power Output (MWh)';
            app.NetPowerOutputMWhEditField = uieditfield(app.OutputReactorSubPanel, 'text');
            app.NetPowerOutputMWhEditField.Editable = 'off';
            app.NetPowerOutputMWhEditField.Value = '0';
            app.HoursInOperationPerYearHrsEditField_2Label = uilabel(app.OutputReactorSubPanel);
            app.HoursInOperationPerYearHrsEditField_2Label.Text = 'Hours In Operation Per Year (Hrs)';
            app.HoursInOperationPerYearHrsEditField_2 = uieditfield(app.OutputReactorSubPanel, 'text');
            app.HoursInOperationPerYearHrsEditField_2.Editable = 'off';
            app.HoursInOperationPerYearHrsEditField_2.Value = '0';
            app.YearlyNetPowerOutputMWhEditField_2Label = uilabel(app.OutputReactorSubPanel);
            app.YearlyNetPowerOutputMWhEditField_2Label.Text = 'Yearly Net Power Output (MWh)';
            app.YearlyNetPowerOutputMWhEditField_2 = uieditfield(app.OutputReactorSubPanel, 'text');
            app.YearlyNetPowerOutputMWhEditField_2.Editable = 'off';
            app.YearlyNetPowerOutputMWhEditField_2.Value = '0';
            app.OutputFuelManTab = uitab(app.OutputNavigationBar);
            app.OutputFuelManTab.Title = 'Fuel Man';
            app.OutputFuelManGrid = uigridlayout(app.OutputFuelManTab);
            app.OutputFuelManPanel = uipanel(app.OutputFuelManGrid);
            app.OutputFuelManPanel.Title = 'Fuel Manufacturing';
            app.OutputFuelManSubGrid = uigridlayout(app.OutputFuelManPanel);
            app.TotalCostPerTargetEditField_2Label = uilabel(app.OutputFuelManSubGrid);
            app.TotalCostPerTargetEditField_2Label.Text = 'Total Cost Per Target ($)';
            app.TotalCostPerTargetEditField_2 = uieditfield(app.OutputFuelManSubGrid, 'text');
            app.TotalCostPerTargetEditField_2.Editable = 'off';
            app.TotalCostPerTargetEditField_2.Value = '0';
            app.TotalFuelManufacturingCostsUpfrontEditField_2Label = uilabel(app.OutputFuelManSubGrid);
            app.TotalFuelManufacturingCostsUpfrontEditField_2Label.Text = 'Total Fuel Manufacturing Costs Upfront ($)';
            app.TotalFuelManufacturingCostsUpfrontEditField_2 = uieditfield(app.OutputFuelManSubGrid, 'text');
            app.TotalFuelManufacturingCostsUpfrontEditField_2.Editable = 'off';
            app.TotalFuelManufacturingCostsUpfrontEditField_2.Value = '0';
            app.TotalFuelManufacturingCostsOngoingYearEditFieldLabel = uilabel(app.OutputFuelManSubGrid);
            app.TotalFuelManufacturingCostsOngoingYearEditFieldLabel.Text = 'Total Fuel Manufacturing Costs Ongoing ($/Year)';
            app.TotalFuelManufacturingCostsOngoingYearEditField = uieditfield(app.OutputFuelManSubGrid, 'text');
            app.TotalFuelManufacturingCostsOngoingYearEditField.Editable = 'off';
            app.TotalFuelManufacturingCostsOngoingYearEditField.Value = '0';
            app.OutputFuelStoreTab = uitab(app.OutputNavigationBar);
            app.OutputFuelStoreTab.Title = 'Fuel Store';
            app.OutputFuelStoreGrid = uigridlayout(app.OutputFuelStoreTab);
            app.OutputFuelStorePanel = uipanel(app.OutputFuelStoreGrid);
            app.OutputFuelStorePanel.Title = 'Fuel Storage and Delivery';
            app.OutputFuelStoreSubGrid = uigridlayout(app.OutputFuelStorePanel);
            app.TotalCostofFuelDeliveryandStorageEditField_2Label = uilabel(app.OutputFuelStoreSubGrid);
            app.TotalCostofFuelDeliveryandStorageEditField_2Label.Text = 'Total Cost of Fuel Delivery and Storage ($)';
            app.TotalCostofFuelDeliveryandStorageEditField_2 = uieditfield(app.OutputFuelStoreSubGrid, 'text');
            app.TotalCostofFuelDeliveryandStorageEditField_2.Editable = 'off';
            app.TotalCostofFuelDeliveryandStorageEditField_2.Value = '0';
            app.OutputMaintenanceTab = uitab(app.OutputNavigationBar);
            app.OutputMaintenanceTab.Title = 'Maintenance';
            app.OutputMaintenanceGrid = uigridlayout(app.OutputMaintenanceTab);
            app.OutputMaintenancePanel = uipanel(app.OutputMaintenanceGrid);
            app.OutputMaintenancePanel.Title = 'Maintenance';
            app.OutputMaintenanceSubGrid = uigridlayout(app.OutputMaintenancePanel);
            app.LifetimeMaintenanceCostEditField_2Label = uilabel(app.OutputMaintenanceSubGrid);
            app.LifetimeMaintenanceCostEditField_2Label.Text = 'Lifetime Maintenance Cost ($)';
            app.LifetimeMaintenanceCostEditField_2 = uieditfield(app.OutputMaintenanceSubGrid, 'text');
            app.LifetimeMaintenanceCostEditField_2.Editable = 'off';
            app.LifetimeMaintenanceCostEditField_2.Value = '0';
            app.OutputConstructionTab = uitab(app.OutputNavigationBar);
            app.OutputConstructionTab.Title = 'Construction';
            app.OutputConstructionGrid = uigridlayout(app.OutputConstructionTab);
            app.OutputConstructionPanel = uipanel(app.OutputConstructionGrid);
            app.OutputConstructionPanel.Title = 'Construction';
            app.OutputConstructionSubGrid = uigridlayout(app.OutputConstructionPanel);
            app.TotalConstructionCostEditField_2Label = uilabel(app.OutputConstructionSubGrid);
            app.TotalConstructionCostEditField_2Label.Text = 'Total Construction Cost ($)';
            app.TotalConstructionCostEditField_2 = uieditfield(app.OutputConstructionSubGrid, 'text');
            app.TotalConstructionCostEditField_2.Editable = 'off';
            app.TotalConstructionCostEditField_2.Value = '0';
            app.OutputCoolingTab = uitab(app.OutputNavigationBar);
            app.OutputCoolingTab.Title = 'Cooling';
            app.OutputCoolingGrid = uigridlayout(app.OutputCoolingTab);
            app.OutputCoolingPanel = uipanel(app.OutputCoolingGrid);
            app.OutputCoolingPanel.Title = 'Cooling System';
            app.OutputCoolingSubGrid = uigridlayout(app.OutputCoolingPanel);
            app.OutputPersonnelTab = uitab(app.OutputNavigationBar);
            app.OutputPersonnelTab.Title = 'Personnel';
            app.OutputPersonnelGrid = uigridlayout(app.OutputPersonnelTab);
            app.OutputPersonnelPanel = uipanel(app.OutputPersonnelGrid);
            app.OutputPersonnelPanel.Title = 'Personnel';
            app.OutputPersonnelSubPanel = uigridlayout(app.OutputPersonnelPanel);
            app.YearlyPersonnelCostEditField_2Label = uilabel(app.OutputPersonnelSubPanel);
            app.YearlyPersonnelCostEditField_2Label.Text = 'Yearly Personnel Cost ($)';
            app.YearlyPersonnelCostEditField_2 = uieditfield(app.OutputPersonnelSubPanel, 'text');
            app.YearlyPersonnelCostEditField_2.Editable = 'off';
            app.YearlyPersonnelCostEditField_2.Value = '0';
            app.OutputVacuumTab = uitab(app.OutputNavigationBar);
            app.OutputVacuumTab.Title = 'Vacuum';
            app.OutputVacuumGrid = uigridlayout(app.OutputVacuumTab);
            app.OutputVacuumPanel = uipanel(app.OutputVacuumGrid);
            app.OutputVacuumPanel.Title = 'Vacuum System';
            app.OutputVacuumSubGrid = uigridlayout(app.OutputVacuumPanel);
            app.TotalCostofVacuumSystemEditFieldLabel = uilabel(app.OutputVacuumSubGrid);
            app.TotalCostofVacuumSystemEditFieldLabel.Text = 'Total Cost of Vacuum System ($)';
            app.TotalCostofVacuumSystemEditField = uieditfield(app.OutputVacuumSubGrid, 'text');
            app.TotalCostofVacuumSystemEditField.Editable = 'off';
            app.TotalCostofVacuumSystemEditField.Value = '0';
            app.OutputOverallTab = uitab(app.OutputNavigationBar);
            app.OutputOverallTab.Title = 'Overall';
            app.OutputOverallGrid = uigridlayout(app.OutputOverallTab);
            app.OutputOverallPanel = uipanel(app.OutputOverallGrid);
            app.OutputOverallPanel.Title = 'Overall';
            app.OutputOverallSubGrid = uigridlayout(app.OutputOverallPanel);
            app.CapitalCostEditField_2Label = uilabel(app.OutputOverallSubGrid);
            app.CapitalCostEditField_2Label.Text = 'Capital Cost ($)';
            app.CapitalCostEditField_2 = uieditfield(app.OutputOverallSubGrid, 'text');
            app.CapitalCostEditField_2.Editable = 'off';
            app.CapitalCostEditField_2.Value = '0';
            app.YearlyCostEditField_2Label = uilabel(app.OutputOverallSubGrid);
            app.YearlyCostEditField_2Label.Text = 'Yearly Cost ($)';
            app.YearlyCostEditField_2 = uieditfield(app.OutputOverallSubGrid, 'text');
            app.YearlyCostEditField_2.Editable = 'off';
            app.YearlyCostEditField_2.Value = '0';
            app.YearlyGrossRevenueEditField_2Label = uilabel(app.OutputOverallSubGrid);
            app.YearlyGrossRevenueEditField_2Label.Text = 'Yearly Gross Revenue ($)';
            app.YearlyGrossRevenueEditField_2 = uieditfield(app.OutputOverallSubGrid, 'text');
            app.YearlyGrossRevenueEditField_2.Editable = 'off';
            app.YearlyGrossRevenueEditField_2.Value = '0';
            app.YearlyProfitEditField_2Label = uilabel(app.OutputOverallSubGrid);
            app.YearlyProfitEditField_2Label.Text = 'Yearly Profit ($)';
            app.YearlyProfitEditField_2 = uieditfield(app.OutputOverallSubGrid, 'text');
            app.YearlyProfitEditField_2.Editable = 'off';
            app.YearlyProfitEditField_2.Value = '0';
            app.LifetimeCostWithoutInflationEditField_2Label = uilabel(app.OutputOverallSubGrid);
            app.LifetimeCostWithoutInflationEditField_2Label.Text = 'Lifetime Cost Without Inflation ($)';
            app.LifetimeCostWithoutInflationEditField_2 = uieditfield(app.OutputOverallSubGrid, 'text');
            app.LifetimeCostWithoutInflationEditField_2.Editable = 'off';
            app.LifetimeCostWithoutInflationEditField_2.Value = '0';
            app.LifetimeProfitWithoutInflationEditField_2Label = uilabel(app.OutputOverallSubGrid);
            app.LifetimeProfitWithoutInflationEditField_2Label.Text = 'Lifetime Profit Without Inflation ($)';
            app.LifetimeProfitWithoutInflationEditField_2 = uieditfield(app.OutputOverallSubGrid, 'text');
            app.LifetimeProfitWithoutInflationEditField_2.Editable = 'off';
            app.LifetimeProfitWithoutInflationEditField_2.Value = '0';
            app.OutputLCOETab = uitab(app.OutputNavigationBar);
            app.OutputLCOETab.Title = 'LCOE';
            app.OutputLCOEGrid = uigridlayout(app.OutputLCOETab);
            app.OutputLCOEPanel = uipanel(app.OutputLCOEGrid);
            app.OutputLCOEPanel.Title = 'LCOE';
            app.OutputLCOESubGrid = uigridlayout(app.OutputLCOEPanel);
            app.AnnualCostofUpfrontCapitalEditField_2Label = uilabel(app.OutputLCOESubGrid);
            app.AnnualCostofUpfrontCapitalEditField_2Label.Text = 'Annual Cost of Upfront Capital ($)';
            app.AnnualCostofUpfrontCapitalEditField_2 = uieditfield(app.OutputLCOESubGrid, 'text');
            app.AnnualCostofUpfrontCapitalEditField_2.Editable = 'off';
            app.AnnualCostofUpfrontCapitalEditField_2.Value = '0';
            app.AnnualCostofOperationsandMaintenanceEditField_2Label = uilabel(app.OutputLCOESubGrid);
            app.AnnualCostofOperationsandMaintenanceEditField_2Label.Text = 'Annual Cost of Operations and Maintenance ($)';
            app.AnnualCostofOperationsandMaintenanceEditField_2 = uieditfield(app.OutputLCOESubGrid, 'text');
            app.AnnualCostofOperationsandMaintenanceEditField_2.Editable = 'off';
            app.AnnualCostofOperationsandMaintenanceEditField_2.Value = '0';
            app.AnnualCostofScheduledPartReplacementsEditField_2Label = uilabel(app.OutputLCOESubGrid);
            app.AnnualCostofScheduledPartReplacementsEditField_2Label.Text = 'Annual Cost of Scheduled Part Replacements ($)';
            app.AnnualCostofScheduledPartReplacementsEditField_2 = uieditfield(app.OutputLCOESubGrid, 'text');
            app.AnnualCostofScheduledPartReplacementsEditField_2.Editable = 'off';
            app.AnnualCostofScheduledPartReplacementsEditField_2.Value = '0';
            app.AnnualCostofFuelEditField_2Label = uilabel(app.OutputLCOESubGrid);
            app.AnnualCostofFuelEditField_2Label.Text = 'Annual Cost of Fuel ($)';
            app.AnnualCostofFuelEditField_2 = uieditfield(app.OutputLCOESubGrid, 'text');
            app.AnnualCostofFuelEditField_2.Editable = 'off';
            app.AnnualCostofFuelEditField_2.Value = '0';
            app.CostofDecomissioningMWhEditField_2Label = uilabel(app.OutputLCOESubGrid);
            app.CostofDecomissioningMWhEditField_2Label.Text = 'Cost of Decomissioning ($/MWh)';
            app.CostofDecomissioningMWhEditField_2 = uieditfield(app.OutputLCOESubGrid, 'text');
            app.CostofDecomissioningMWhEditField_2.Editable = 'off';
            app.CostofDecomissioningMWhEditField_2.Value = '0';
            app.LCOEMWhEditField_2Label = uilabel(app.OutputLCOESubGrid);
            app.LCOEMWhEditField_2Label.Text = 'LCOE ($/MWh)';
            app.LCOEMWhEditField_2 = uieditfield(app.OutputLCOESubGrid, 'text');
            app.LCOEMWhEditField_2.Editable = 'off';
            app.LCOEMWhEditField_2.Value = '0';
            app.OutputProjectionsTab = uitab(app.OutputNavigationBar);
            app.OutputProjectionsTab.Title = 'Projections';
            app.OutputProjectionsGrid = uipanel(app.OutputProjectionsTab);
            app.OutputProjectionsGrid.Title = 'Financial Projections';
            app.OutputProjectionsSubGrid = uigridlayout(app.OutputProjectionsGrid);
            app.UIAxes = uiaxes(app.OutputProjectionsSubGrid);
            title(app.UIAxes, 'Total Project Cost vs Time')
            xlabel(app.UIAxes, 'Time (Years)')
            ylabel(app.UIAxes, 'Total Project Cost ($)')
            zlabel(app.UIAxes, 'Z')
            app.OutputProjectionsSubSubGrid = uigridlayout(app.OutputProjectionsSubGrid);
            app.ProjectedLifetimeCostEditFieldLabel = uilabel(app.OutputProjectionsSubSubGrid);
            app.ProjectedLifetimeCostEditFieldLabel.Text = 'Projected Lifetime Cost ($)';
            app.ProjectedLifetimeCostEditField = uieditfield(app.OutputProjectionsSubSubGrid, 'text');
            app.ProjectedLifetimeCostEditField.Editable = 'off';
            app.ProjectedLifetimeCostEditField.Value = '0';
            app.ProjectedLifetimeGrossRevenueEditFieldLabel = uilabel(app.OutputProjectionsSubSubGrid);
            app.ProjectedLifetimeGrossRevenueEditFieldLabel.Text = 'Projected Lifetime Gross Revenue ($)';
            app.ProjectedLifetimeGrossRevenueEditField = uieditfield(app.OutputProjectionsSubSubGrid, 'text');
            app.ProjectedLifetimeGrossRevenueEditField.Editable = 'off';
            app.ProjectedLifetimeGrossRevenueEditField.Value = '0';
            app.ProjectedLifetimeNetRevenueEditFieldLabel = uilabel(app.OutputProjectionsSubSubGrid);
            app.ProjectedLifetimeNetRevenueEditFieldLabel.Text = 'Projected Lifetime Net Revenue ($)';
            app.ProjectedLifetimeNetRevenueEditField = uieditfield(app.OutputProjectionsSubSubGrid, 'text');
            app.ProjectedLifetimeNetRevenueEditField.Editable = 'off';
            app.ProjectedLifetimeNetRevenueEditField.Value = '0';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.OptionsLayout = uigridlayout(app.OptionsTab);
            app.OpenaprevioussessionLabel = uilabel(app.OptionsLayout);
            app.OpenaprevioussessionLabel.Text = 'Open a previous session';
            app.OpenButton = uibutton(app.OptionsLayout, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SavethissessionLabel = uilabel(app.OptionsLayout);
            app.SavethissessionLabel.Text = 'Save this session';
            app.SaveButton = uibutton(app.OptionsLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HB11TEA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end